classdef proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        FilePathEditFieldLabel  matlab.ui.control.Label
        FilePathEditField       matlab.ui.control.EditField
        BrowseButton            matlab.ui.control.Button
        Panel_2                 matlab.ui.container.Panel
        Slider                  matlab.ui.control.Slider
        Slider_2                matlab.ui.control.Slider
        Slider_3                matlab.ui.control.Slider
        Slider_4                matlab.ui.control.Slider
        Slider_5                matlab.ui.control.Slider
        Slider_6                matlab.ui.control.Slider
        Slider_7                matlab.ui.control.Slider
        Slider_8                matlab.ui.control.Slider
        Slider_9                matlab.ui.control.Slider
        Slider_10               matlab.ui.control.Slider
        HzLabel                 matlab.ui.control.Label
        HzLabel_2               matlab.ui.control.Label
        HzLabel_3               matlab.ui.control.Label
        HzLabel_4               matlab.ui.control.Label
        kHzLabel                matlab.ui.control.Label
        kHzLabel_2              matlab.ui.control.Label
        kHzLabel_3              matlab.ui.control.Label
        kHzLabel_4              matlab.ui.control.Label
        kHzLabel_5              matlab.ui.control.Label
        kHzLabel_6              matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        Label_6                 matlab.ui.control.Label
        Label_7                 matlab.ui.control.Label
        Label_8                 matlab.ui.control.Label
        Label_9                 matlab.ui.control.Label
        Label_10                matlab.ui.control.Label
        Label_11                matlab.ui.control.Label
        Label_12                matlab.ui.control.Label
        Label_13                matlab.ui.control.Label
        Panel_3                 matlab.ui.container.Panel
        PlayButton              matlab.ui.control.Button
        PauseButton             matlab.ui.control.Button
        ResumeButton            matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        Slider_11               matlab.ui.control.Slider
        VolumeLabel             matlab.ui.control.Label
        Panel_4                 matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        init_path = ""; % Path to open the file dialog
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile(strcat(app.init_path, '*.wav'));
            if isequal(file,0)
               app.FilePathEditField.Value = "";
            else
               app.FilePathEditField.Value = fullfile(path,file);
               app.init_path = path;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.FilePathEditFieldLabel = uilabel(app.Panel);
            app.FilePathEditFieldLabel.Text = 'File Path:';
            app.FilePathEditField = uieditfield(app.Panel, 'text');
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Slider = uislider(app.Panel_2);
            app.Slider.Limits = [-15 15];
            app.Slider.MajorTicks = [];
            app.Slider.MinorTicks = [];
            app.Slider_2 = uislider(app.Panel_2);
            app.Slider_2.Limits = [-15 15];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MinorTicks = [];
            app.Slider_3 = uislider(app.Panel_2);
            app.Slider_3.Limits = [-15 15];
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MinorTicks = [];
            app.Slider_4 = uislider(app.Panel_2);
            app.Slider_4.Limits = [-15 15];
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MinorTicks = [];
            app.Slider_5 = uislider(app.Panel_2);
            app.Slider_5.Limits = [-15 15];
            app.Slider_5.MajorTicks = [];
            app.Slider_5.MinorTicks = [];
            app.Slider_6 = uislider(app.Panel_2);
            app.Slider_6.Limits = [-15 15];
            app.Slider_6.MajorTicks = [];
            app.Slider_6.MinorTicks = [];
            app.Slider_7 = uislider(app.Panel_2);
            app.Slider_7.Limits = [-15 15];
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MinorTicks = [];
            app.Slider_8 = uislider(app.Panel_2);
            app.Slider_8.Limits = [-15 15];
            app.Slider_8.MajorTicks = [];
            app.Slider_8.MinorTicks = [];
            app.Slider_9 = uislider(app.Panel_2);
            app.Slider_9.Limits = [-15 15];
            app.Slider_9.MajorTicks = [];
            app.Slider_9.MinorTicks = [];
            app.Slider_10 = uislider(app.Panel_2);
            app.Slider_10.Limits = [-15 15];
            app.Slider_10.MajorTicks = [];
            app.Slider_10.MinorTicks = [];
            app.HzLabel = uilabel(app.Panel_2);
            app.HzLabel.Text = '0-200Hz';
            app.HzLabel_2 = uilabel(app.Panel_2);
            app.HzLabel_2.Text = '201-400Hz';
            app.HzLabel_3 = uilabel(app.Panel_2);
            app.HzLabel_3.Text = '401-800Hz';
            app.HzLabel_4 = uilabel(app.Panel_2);
            app.HzLabel_4.Text = '801-1500Hz';
            app.kHzLabel = uilabel(app.Panel_2);
            app.kHzLabel.Text = '1.5-2kHz';
            app.kHzLabel_2 = uilabel(app.Panel_2);
            app.kHzLabel_2.Text = '3-5kHz';
            app.kHzLabel_3 = uilabel(app.Panel_2);
            app.kHzLabel_3.Text = '5-7kHz';
            app.kHzLabel_4 = uilabel(app.Panel_2);
            app.kHzLabel_4.Text = '7-10kHz';
            app.kHzLabel_5 = uilabel(app.Panel_2);
            app.kHzLabel_5.Text = '10-15kHz';
            app.kHzLabel_6 = uilabel(app.Panel_2);
            app.kHzLabel_6.Text = '>15kHz';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '-15';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '15';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.Panel_2);
            app.Label_12.Text = '';
            app.Label_13 = uilabel(app.Panel_2);
            app.Label_13.Text = '';
            app.Panel_3 = uipanel(app.UIFigure);
            app.PlayButton = uibutton(app.Panel_3, 'push');
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.Panel_3, 'push');
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.Panel_3, 'push');
            app.ResumeButton.Text = 'Resume';
            app.StopButton = uibutton(app.Panel_3, 'push');
            app.StopButton.Text = 'Stop';
            app.Slider_11 = uislider(app.Panel_3);
            app.Slider_11.MajorTicks = [];
            app.VolumeLabel = uilabel(app.Panel_3);
            app.VolumeLabel.Text = 'Volume';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Panel_4);
            title(app.UIAxes_2, 'FIltered')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end